classdef exampleIssue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        plotButton   matlab.ui.control.Button
        ProfileAxes  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Obj = struct;
    end
    
    methods (Access = private)
        
        function init_obj(app)       
            object = app.Obj;
            
            vert = object.Vert;
            face = object.Face;
            color = 0.5*ones(size(face,1),3); % assign temporary colour
            
            % Add 2 columns of empty data (zeroes) to 'color'. These columns will
            % be used to store space to save selected faces and surface temps in.
            color(:,4:5) = 0;
            
            % Give each face a default surf temp value of 25. This is arbitrary.
            color(:,5) = 25*ones(size(color,1),1);
            
            % Create patch from Faces and Vertices mapped out above
            patch(app.ProfileAxes,'Vertices',vert,'Faces',face,'FaceVertexCData',color(:,1:3),'FaceColor','flat');
        
            app.ProfileAxes.CameraPositionMode = 'manual';
            app.ProfileAxes.PlotBoxAspectRatio = [1 1 1];
            app.ProfileAxes.PlotBoxAspectRatioMode = 'manual';
            app.ProfileAxes.DataAspectRatioMode = 'manual';
            app.ProfileAxes.View = [-45 25];
            
            % Create very large axes so that axial lines to not interfere with the
            % patch.  Axes must be symmetic (NxNxN)
            app.ProfileAxes.XLim = [-1 1];
            app.ProfileAxes.XLimMode = 'manual';
            app.ProfileAxes.YLim = [-1 1];
            app.ProfileAxes.YLimMode = 'manual';
            app.ProfileAxes.ZLim = [-1 1];
            app.ProfileAxes.ZLimMode = 'manual';
        
            app.ProfileAxes.Position = [-100 -100 1000 1000];
            
            % Set colour of axes to match background colour (windows default)
            % [0.94, 0.94, 0.94]; [0 0 0];
            app.ProfileAxes.XColor = [0.94, 0.94, 0.94];
            app.ProfileAxes.YColor = [0.94, 0.94, 0.94];
            app.ProfileAxes.ZColor = [0.94, 0.94, 0.94];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            load("exampleObj.mat");
            app.Obj = exampleObj;
            init_obj(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProfileAxes = uiaxes(app.UIFigure);
            title(app.ProfileAxes, 'Title')
            xlabel(app.ProfileAxes, 'X')
            ylabel(app.ProfileAxes, 'Y')
            zlabel(app.ProfileAxes, 'Z')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exampleIssue

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end